classdef nonsense < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SizeEditFieldLabel  matlab.ui.control.Label
        SizeEditField       matlab.ui.control.NumericEditField
        DoButton            matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function PictureDev(app)
            s = app.SizeEditField.Value;
            if s
                
                pic = uint8(zeros(s,s,3));
                for y = 1:s
                    for x = 1:s
                        for c = 1:3
                            pic(y,x,c) = uint8(255 * rand);
                        end
                    end
                end
                imwrite(pic, 'nonsense.png', 'BitDepth',8);
                app.UIAxes.Position = [0 0 app.SizeEditField.Position(2) app.SizeEditField.Position(2)];
                imshow('nonsense.png','Parent',app.UIAxes);
                clear pic
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DoButton
        function ImageClicked(app, event)
            
            PictureDev(app);
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SizeEditFieldLabel = uilabel(app.UIFigure);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.DoButton = uibutton(app.UIFigure, 'push');
            app.DoButton.ButtonPushedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.DoButton.Text = 'Do';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nonsense

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end